classdef lab7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        CleanButton         matlab.ui.control.Button
        BuildButton         matlab.ui.control.Button
        cEditField          matlab.ui.control.NumericEditField
        cEditFieldLabel     matlab.ui.control.Label
        bEditField          matlab.ui.control.NumericEditField
        bEditFieldLabel     matlab.ui.control.Label
        aEditField          matlab.ui.control.NumericEditField
        aEditFieldLabel     matlab.ui.control.Label
        NEditField          matlab.ui.control.NumericEditField
        NEditFieldLabel     matlab.ui.control.Label
        ymaxEditField       matlab.ui.control.NumericEditField
        ymaxEditFieldLabel  matlab.ui.control.Label
        yminEditField       matlab.ui.control.NumericEditField
        yminEditFieldLabel  matlab.ui.control.Label
        xmaxEditField       matlab.ui.control.NumericEditField
        xmaxEditFieldLabel  matlab.ui.control.Label
        xminEditField       matlab.ui.control.NumericEditField
        xminEditFieldLabel  matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CleanButton
        function CleanButtonPushed(app, event)
            cla(app.UIAxes);
        end

        % Button pushed function: BuildButton
        function BuildButtonPushed(app, event)
            x_min = app.xminEditField.Value;    % минимум по оси Х
            x_max = app.xmaxEditField.Value;    % максимум по оси Х
            y_min = app.yminEditField.Value;    % минимум по оси Y
            y_max = app.ymaxEditField.Value;    % максимум по оси Y
            N = app.NEditField.Value;           % число точек (отсчётов) по X и Y
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            c = app.cEditField.Value;

            x = linspace(x_min, x_max, N);
            y = linspace(y_min, y_max, N);

            [X, Y] = meshgrid(x, y);

            Zp = abs(c .* sqrt((X./a).^2 + (Y./b).^2 - 1));
            Zn = -abs(c .* sqrt((X./a).^2 + (Y./b).^2 - 1));

            hold(app.UIAxes, "on")

            mesh(app.UIAxes,X, Y, Zp)
            mesh(app.UIAxes,X, Y, Zn)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.xminEditFieldLabel = uilabel(app.UIFigure);
            app.xminEditFieldLabel.Text = 'x min';
            app.xminEditField = uieditfield(app.UIFigure, 'numeric');
            app.xminEditField.Value = -1;
            app.xmaxEditFieldLabel = uilabel(app.UIFigure);
            app.xmaxEditFieldLabel.Text = 'x max';
            app.xmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.xmaxEditField.Value = 1;
            app.yminEditFieldLabel = uilabel(app.UIFigure);
            app.yminEditFieldLabel.Text = 'y min';
            app.yminEditField = uieditfield(app.UIFigure, 'numeric');
            app.yminEditField.Value = -1;
            app.ymaxEditFieldLabel = uilabel(app.UIFigure);
            app.ymaxEditFieldLabel.Text = 'y max';
            app.ymaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.ymaxEditField.Value = 1;
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.NEditField.Value = 1000;
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditField.Value = 0.5;
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditField.Value = 0.5;
            app.cEditFieldLabel = uilabel(app.UIFigure);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.UIFigure, 'numeric');
            app.cEditField.Value = 1;
            app.BuildButton = uibutton(app.UIFigure, 'push');
            app.BuildButton.ButtonPushedFcn = createCallbackFcn(app, @BuildButtonPushed, true);
            app.BuildButton.Text = 'Build';
            app.CleanButton = uibutton(app.UIFigure, 'push');
            app.CleanButton.ButtonPushedFcn = createCallbackFcn(app, @CleanButtonPushed, true);
            app.CleanButton.Text = 'Clean';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab7

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end